classdef vowelVisualizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        ElapsedTimefromStartEditField  matlab.ui.control.NumericEditField
        ElapsedTimefromStartEditFieldLabel  matlab.ui.control.Label
        InitializingLabel              matlab.ui.control.Label
        TimeLeftEditField              matlab.ui.control.NumericEditField
        TimeLeftEditFieldLabel         matlab.ui.control.Label
        STOPButton                     matlab.ui.control.Button
        STARTButton                    matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        foTrjctryAxis                  matlab.ui.control.UIAxes
        f1vsf2Axis                     matlab.ui.control.UIAxes
        totalLevelAxis                 matlab.ui.control.UIAxes
        bandlevelAxis                  matlab.ui.control.UIAxes
        SpectrumAxis                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        CommonData % Shared data
    end
    
    methods (Access = private)
        
        function results = initializeSpectrum(app)
            fs = app.CommonData.baseSamplingFrequency;
            w = blackman(round(0.06*fs)*2+1);
            app.CommonData.w = w;
            fftl = 2^ceil(log2(length(w)));
            app.CommonData.fftl = fftl;
            fx = (0:fftl-1)/fftl*fs;
            app.CommonData.fAxis = fx;
            tt = (1:length(w))'/fs;
            x = sin(2*pi*1000*tt);
            cf = 10*log10(1/fftl/length(w))+5.1628+3;
            app.CommonData.correctionFactor = cf;
            rawPowerSpec = abs(fft(x.*w,fftl)).^2;
            app.CommonData.rawPowerSpec = rawPowerSpec;
            powerSpecDb = 10*log10(rawPowerSpec)+cf;
            fDispLimit = 4000;
            app.CommonData.fDispLimit = fDispLimit;
            fxLimit = fx(fx<=fDispLimit);
            powerSpecLimit = powerSpecDb(fx<=fDispLimit);
            app.CommonData.pwEmphasis = ...
                abs(fft([1 -0.995]'/(1-0.995),fftl)).^2;
            fftLPC = 1364;
            fxLPC = (0:fftLPC-1)*fx(2);
            fsLPC = fftLPC*fx(2);
            r = 1-(1-0.995)/fsLPC*fs;
            app.CommonData.pwEmphasisLPC = ...
                abs(fft([1 -r]'/(1-r),fftLPC)).^2;
            specDbLPC = -10+zeros(fftLPC,1);
            app.CommonData.fftLPC = fftLPC;
            app.CommonData.fxLPC = fxLPC;
            app.CommonData.fsLPC = fsLPC;
            app.CommonData.powerSpectrunHandle = ...
                plot(app.SpectrumAxis,fxLimit/1000, powerSpecLimit, ...
                "LineWidth",2);
            hold(app.SpectrumAxis,"on");
            app.CommonData.lpcSpectrunHandle = ...
                plot(app.SpectrumAxis,fxLPC/1000, specDbLPC, ...
                "LineWidth",2);
            hold(app.SpectrumAxis,"off");
            axis(app.SpectrumAxis,[0 fDispLimit/1000 -90 0]);
            grid(app.SpectrumAxis,"on");
            xlabel(app.SpectrumAxis,"frequency (kHz)");
            ylabel(app.SpectrumAxis,"level (dB rel. MSB)");
            results = 1;
        end
        
        function results = intializeBanLevel(app)
            %fs = app.CommonData.baseSamplingFrequency;
            %rawPowerSpec = app.CommonData.rawPowerSpec;
            fc = 1000*2.^(-5:1/3:4)';
            fcL = fc*2^(-1/6);
            fcH = fc*2^(1/6);
            app.CommonData.fc = fc;
            app.CommonData.fcL = fcL;
            app.CommonData.fcH = fcH;
            cumPower = cumsum(app.CommonData.rawPowerSpec);
            fx = app.CommonData.fAxis;
            bandLevel = interp1(fx,cumPower,fcH,"linear","extrap") ...
                - interp1(fx,cumPower,fcL,"linear","extrap");
            bandLevelDb = 10*log10(bandLevel)+app.CommonData.correctionFactor;
            app.CommonData.bandLevelHandle = ...
                bar(app.bandlevelAxis,bandLevelDb,"BaseValue",-90, ...
                "BarWidth",1,"FaceColor",[0 1 0]);
            grid(app.bandlevelAxis,"on");
            axis(app.bandlevelAxis,[1 length(fc) -90 0]);
            rawTick = [50 100 200 500 1000 2000 5000 10000];
            xTickLocation = interp1(log2(fc),1:length(fc),log2(rawTick));
            xlabelcel = cell(length(rawTick));
            for ii = 1:length(rawTick)
                xlabelcel{ii} = num2str(rawTick(ii)/1000);
            end
            set(app.bandlevelAxis,"XTick",xTickLocation, ...
                "XTickLabel",xlabelcel);
            xlabel(app.bandlevelAxis,"frequency (kHz)");
            ylabel(app.bandlevelAxis,"level (dB rel. MSB)");
            results = 1; 
        end
        
        function results = updateSpectrum(app)
            x = app.CommonData.segment;
            fftl = app.CommonData.fftl;
            w = app.CommonData.w;
            rawPowerSpec = abs(fft(x.*w,fftl)).^2;
            app.CommonData.rawPowerSpec = rawPowerSpec;
            cf = app.CommonData.correctionFactor;
            powerSpecDb = 10*log10(rawPowerSpec)+cf;
            fx = app.CommonData.fAxis;
            %app.CommonData.fDispLimit
            powerSpecLimit = powerSpecDb(fx<=app.CommonData.fDispLimit);
            set(app.CommonData.powerSpectrunHandle, "YData",powerSpecLimit);
            %drawnow limitrate nocallbacks;
            results = 1;
        end
        
        function results = updateBandLevel(app)
            %fc = app.CommonData.fc;
            fcL = app.CommonData.fcL;
            fcH = app.CommonData.fcH;
            cumPower = cumsum(app.CommonData.rawPowerSpec);
            fx = app.CommonData.fAxis;
            bandLevel = interp1(fx,cumPower,fcH,"linear","extrap") ...
                - interp1(fx,cumPower,fcL,"linear","extrap");
            bandLevelDb = 10*log10(bandLevel)+app.CommonData.correctionFactor;
            set(app.CommonData.bandLevelHandle,"YData",bandLevelDb);
            %drawnow limitrate nocallbacks;
            results = 1;
        end
        
        function results = lpcSpec(app)
            fftLPC = app.CommonData.fftLPC;
            fx = app.CommonData.fAxis;
            fxLPC = app.CommonData.fxLPC;
            fsLPC = app.CommonData.fsLPC;
            fUp = 3600;
            empPower = ...
                app.CommonData.rawPowerSpec.*app.CommonData.pwEmphasis;
            emPowerTmp = empPower(1:fftLPC/2+1);
            emPowerTrim = [emPowerTmp;emPowerTmp(end-1:-1:2)];
            acorr = real(ifft(emPowerTrim));
            p = 12;
            [aa,~,~] = levinson(acorr,p);
            lpcPower = 1.0./abs(fft(aa(:)/sum(aa),fftLPC)).^2;
            orgPower = sum(empPower(fx<fUp)*fx(2));
            tmpPower = sum(lpcPower(fxLPC<fUp)*fxLPC(2));
            lpcPower = lpcPower./app.CommonData.pwEmphasisLPC/tmpPower*orgPower;
            lpcPowerDb = 10*log10(lpcPower)+app.CommonData.correctionFactor;
            set(app.CommonData.lpcSpectrunHandle, "YData",lpcPowerDb);
            rawRoot = roots(aa);
            rawrootFreq = angle(rawRoot)/2/pi*fsLPC;
            rawrootBw = -1/2*(fsLPC/(2*pi))*log(abs(rawRoot));
            rawPowerTmp = app.CommonData.rawPowerSpec(1:fftLPC/2+1);
            rawPowerTrim = [rawPowerTmp;rawPowerTmp(end-1:-1:2)];
            normalPower = rawPowerTrim ./ lpcPower;
            normalAcorr = real(ifft(normalPower));
            lagAxis = (0:fftLPC-1)'/fsLPC;
            lagAxis(1) = lagAxis(2);
            tmpFoAxis = 1.0./lagAxis;
            foHlim = 700;
            foLlim = 70;
            normalAcorrTrim = normalAcorr(tmpFoAxis > foLlim & tmpFoAxis < foHlim);
            tmpFoAxisTrim = tmpFoAxis(tmpFoAxis > foLlim & tmpFoAxis < foHlim);
            [~, maxLag] = max(normalAcorrTrim);
            app.CommonData.currentfo = tmpFoAxisTrim(maxLag); % need refine
            %[180 1200 600 3200]
            rawrootTrim = rawrootFreq(rawrootFreq>180 & rawrootFreq <3200 ...
                & rawrootBw < 120);
            if length(rawrootTrim) > 1
                sortedFreq = sort(rawrootTrim);
                app.CommonData.currentF1 = sortedFreq(1);
                app.CommonData.currentF2 = sortedFreq(2);
            else
                app.CommonData.currentF1 = 0;
                app.CommonData.currentF2 = 0;
            end
            if app.CommonData.totalLevel(end) < -30
                app.CommonData.currentF1 = 0;
                app.CommonData.currentF2 = 0;
                app.CommonData.currentfo = 0;
            end
            %drawnow limitrate nocallbacks;
            results = 1;
        end
        
        function results = initializeTotalLevel(app)
            viewDuration = app.CommonData.viewDuration;
            skipTime = app.CommonData.skipTime;
            viewerTime = (0:skipTime:viewDuration)' - viewDuration;
            totalLevel = rand(length(viewerTime),1) * 50 - 60;
            app.CommonData.viewerTime = viewerTime;
            app.CommonData.totalLevel = totalLevel;
            app.CommonData.totalLevelHandle = plot(app.totalLevelAxis, ...
                viewerTime,totalLevel,"ko-","LineWidth",2,...
                "MarkerSize",3);
            axis(app.totalLevelAxis,[viewerTime(1) viewerTime(end) ...
                -70 0]);
            xlabel(app.totalLevelAxis,"lag time (s)");
            ylabel(app.totalLevelAxis,"level (dB rel. MSB)");
            grid(app.totalLevelAxis,"on");
            results = 1;
        end
        
        function results = updateTotalLevel(app)
            fs = app.CommonData.baseSamplingFrequency;
            fx = app.CommonData.fAxis;
            totalPower = sum(app.CommonData.rawPowerSpec(fx<fs/2));
            nFrame = length(app.CommonData.viewerTime);
            app.CommonData.viewerTime(1:nFrame-1) = ...
                app.CommonData.viewerTime(2:nFrame);
            app.CommonData.viewerTime(nFrame) = ...
                app.CommonData.elapsedTime;
            app.CommonData.totalLevel(1:nFrame-1) = ...
                app.CommonData.totalLevel(2:nFrame);
            app.CommonData.totalLevel(nFrame) = ...
                10*log10(totalPower)+app.CommonData.correctionFactor;
            set(app.CommonData.totalLevelHandle, ...
                "XData",app.CommonData.viewerTime-app.CommonData.elapsedTime, ...
                "YData",app.CommonData.totalLevel);
            results = 1;
        end
        
        function results = initializeF1vsF2(app)
            nFrame = length(app.CommonData.viewerTime);
            app.CommonData.f1Freq = ...
                2.0.^(rand(nFrame,1)*(log2(1400)-log2(150))+log2(150));
            app.CommonData.f2Freq = ...
                2.0.^(rand(nFrame,1)*(log2(3800)-log2(500))+log2(500));
            app.CommonData.formantHandle = ...
                loglog(app.f1vsf2Axis,app.CommonData.f1Freq, ...
                app.CommonData.f2Freq, ...
                "ko","MarkerSize",2,"LineWidth",1,"MarkerFaceColor",[0 0 0]);
            hold(app.f1vsf2Axis,"on");
            app.CommonData.currentformantHandle = ...
                loglog(app.f1vsf2Axis,500, ...
                1500, ...
                "o","MarkerSize",16,"MarkerFaceColor",[0 1 0], ...
                "LineWidth",2);
            axis(app.f1vsf2Axis,[180 1200 500 3200]);
            grid(app.f1vsf2Axis,"on");
            xlabel(app.f1vsf2Axis,"F1 frequency (Hz)");
            ylabel(app.f1vsf2Axis,"F2 frequency (Hz)");
            results = 1;
        end
        
        function results = updateFormant(app)
            nFrame = length(app.CommonData.viewerTime);
            app.CommonData.f1Freq(1:nFrame-1) = ...
                app.CommonData.f1Freq(2:nFrame);
            app.CommonData.f1Freq(nFrame) = ...
                app.CommonData.currentF1;
            app.CommonData.f2Freq(1:nFrame-1) = ...
                app.CommonData.f2Freq(2:nFrame);
            app.CommonData.f2Freq(nFrame) = ...
                app.CommonData.currentF2;
            set(app.CommonData.currentformantHandle, ...
                "XData",app.CommonData.currentF1, ...
                "YData",app.CommonData.currentF2);
            set(app.CommonData.formantHandle, ...
                "XData",app.CommonData.f1Freq, ...
                "YData",app.CommonData.f2Freq);
            results = 1;
        end
        
        function results = intializefoViewer(app)
            nFrame = length(app.CommonData.viewerTime);
            app.CommonData.foTrajectory = zeros(nFrame,1)+ 110;
            viewerTime = app.CommonData.viewerTime;
            app.CommonData.foTrjHandle = semilogy(app.foTrjctryAxis, ...
                viewerTime,app.CommonData.foTrajectory, "ko-", ...
                "MarkerSize",3,"LineWidth",2);
            axis(app.foTrjctryAxis,[viewerTime(1) viewerTime(end) 70 700]);
            grid(app.foTrjctryAxis,"on");
            xlabel(app.foTrjctryAxis,"lag time (s)");
            ylabel(app.foTrjctryAxis,"fo (Hz)");
            results = 1;
        end
        
        function results = updateFoViewer(app)
            nFrame = length(app.CommonData.viewerTime);
            app.CommonData.foTrajectory(1:nFrame-1) = ...
                app.CommonData.foTrajectory(2:nFrame);
            app.CommonData.foTrajectory(nFrame) = ...
                app.CommonData.currentfo;
            set(app.CommonData.foTrjHandle, ...
                "XData",app.CommonData.viewerTime-app.CommonData.elapsedTime, ...
                "YData",app.CommonData.foTrajectory);
            results = 1;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CommonData.baseSamplingFrequency = 48000;
            fs = app.CommonData.baseSamplingFrequency;
            tt = (1:fs)'/fs;
            x = sqrt(hanning(fs)).*(2*sin(2*pi*220*tt) ...
                +cos(2*pi*220*tt*2^(4/12))+0.5*sin(2*pi*220*tt*2^(7/12))...
                +2*cos(2*pi*110*tt)).*exp(-tt/0.1);
            try
                %sound(x/max(x)*0.3,fs);
                player = audioplayer(x/max(x)*0.3,fs,24);
                playblocking(player);
            catch
                message =  {'音を再生するのに失敗しました。', ...
                    '音が再生できることを確認してから','アプリケーションを立ちあげ直して下さい。', ...
                    '5秒後に、このアプリケーションは終了します。'};
                uialert(app.UIFigure,message,"再生失敗");
                pause(5)
                close(app.UIFigure);
            end
            app.CommonData.skipTime = 0.02;
            app.CommonData.viewDuration = 5;
            initializeSpectrum(app);
            intializeBanLevel(app);
            initializeTotalLevel(app);
            initializeF1vsF2(app);
            intializefoViewer(app);
            drawnow;
            app.STOPButton.Enable = "off";
            app.STARTButton.Enable = "on";
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            fs = app.CommonData.baseSamplingFrequency;
            app.CommonData.recorder = audiorecorder(fs,24,1);
            app.STARTButton.Enable = "off";
            app.STOPButton.Enable = "on";
            wlength = length(app.CommonData.w);
            record(app.CommonData.recorder);
            app.CommonData.startTime = datetime('now');
            pause(0.1);
            y = getaudiodata(app.CommonData.recorder);
            nBias = length(y);
            runningQ = string(app.CommonData.recorder.Running);
            skipTime = app.CommonData.skipTime;
            while runningQ == "on" %&& timeLeft > 0
                pause(skipTime);
                y = getaudiodata(app.CommonData.recorder);
                currentTime = datetime('now');
                app.CommonData.elapsedTime = ...
                    seconds(currentTime-app.CommonData.startTime);
                app.ElapsedTimefromStartEditField.Value = ...
                    app.CommonData.elapsedTime;
                timeLeft = round((20 - (length(y)-nBias)/fs)*100)/100;
                runningQ = string(app.CommonData.recorder.Running);
                app.TimeLeftEditField.Value = timeLeft;
                if length(y) > wlength
                    app.CommonData.segment = y(end-wlength+1:end);
                    updateSpectrum(app);
                    updateBandLevel(app);
                    updateTotalLevel(app);
                    lpcSpec(app);
                    updateFormant(app);
                    updateFoViewer(app);
                    drawnow limitrate nocallbacks;
                end
                if timeLeft <= 0
                    app.TimeLeftEditField.Visible = "off";
                    app.InitializingLabel.Visible = "on";
                    stop(app.CommonData.recorder);
                    pause(0.1);
                    record(app.CommonData.recorder);
                    pause(0.1);
                    y = getaudiodata(app.CommonData.recorder);
                    nBias = length(y);
                    app.InitializingLabel.Visible = "off";
                    app.TimeLeftEditField.Visible = "on";
                end
            end
            app.TimeLeftEditField.Value = 20;
            stop(app.CommonData.recorder);
            intializeBanLevel(app);
            app.STOPButton.Enable = "off";
            app.STARTButton.Enable = "on";
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            stop(app.CommonData.recorder);
            app.STOPButton.Enable = "off";
            app.STARTButton.Enable = "on";
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {647, 647};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.STARTButton = uibutton(app.LeftPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.STOPButton = uibutton(app.LeftPanel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.TimeLeftEditFieldLabel = uilabel(app.LeftPanel);
            app.TimeLeftEditFieldLabel.Text = 'Time Left';
            app.TimeLeftEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TimeLeftEditField.ValueDisplayFormat = '%7.2f';
            app.TimeLeftEditField.Value = 20;
            app.InitializingLabel = uilabel(app.LeftPanel);
            app.InitializingLabel.Enable = 'off';
            app.InitializingLabel.Visible = 'off';
            app.InitializingLabel.Text = 'Initializing ...';
            app.ElapsedTimefromStartEditFieldLabel = uilabel(app.LeftPanel);
            app.ElapsedTimefromStartEditFieldLabel.Text = 'Elapsed Time from Start';
            app.ElapsedTimefromStartEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ElapsedTimefromStartEditField.ValueDisplayFormat = '%15.4f';
            app.RightPanel = uipanel(app.GridLayout);
            app.SpectrumAxis = uiaxes(app.RightPanel);
            title(app.SpectrumAxis, 'spectrum')
            xlabel(app.SpectrumAxis, 'X')
            ylabel(app.SpectrumAxis, 'level (dB)')
            zlabel(app.SpectrumAxis, 'Z')
            app.bandlevelAxis = uiaxes(app.RightPanel);
            title(app.bandlevelAxis, 'band level')
            xlabel(app.bandlevelAxis, 'X')
            ylabel(app.bandlevelAxis, 'level (dB)')
            zlabel(app.bandlevelAxis, 'Z')
            app.totalLevelAxis = uiaxes(app.RightPanel);
            title(app.totalLevelAxis, 'Total level')
            xlabel(app.totalLevelAxis, 'X')
            ylabel(app.totalLevelAxis, 'level (dB)')
            zlabel(app.totalLevelAxis, 'Z')
            app.f1vsf2Axis = uiaxes(app.RightPanel);
            title(app.f1vsf2Axis, 'band level')
            xlabel(app.f1vsf2Axis, 'X')
            ylabel(app.f1vsf2Axis, 'level (dB)')
            zlabel(app.f1vsf2Axis, 'Z')
            app.foTrjctryAxis = uiaxes(app.RightPanel);
            title(app.foTrjctryAxis, 'Total level')
            xlabel(app.foTrjctryAxis, 'X')
            ylabel(app.foTrjctryAxis, 'level (dB)')
            zlabel(app.foTrjctryAxis, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vowelVisualizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end